classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SelectButton              matlab.ui.control.Button
        ImagePathLabel            matlab.ui.control.Label
        CategoriesPanel           matlab.ui.container.Panel
        HealthyCheckBox           matlab.ui.control.CheckBox
        MultiplediseasesCheckBox  matlab.ui.control.CheckBox
        RustCheckBox              matlab.ui.control.CheckBox
        ScabCheckBox              matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
        Image                     matlab.ui.control.Image
        TitleLabel                matlab.ui.control.Label
        RotateButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        detector % Yolov2 Trained Detector
        img
        healthyBoxes
        heathyLabels
        multiBoxes
        multiLabels
        rustBoxes
        rustLabels
        scabBoxes
        scabLabels
    end
    
    methods (Access = private)
        
        function foliarDiseaseDetection(app)
            % Progress bar
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Process the image');
            inputSize = [224 224 3];
            % Resize the image
            app.img = imresize(app.img,inputSize(1:2));
            % Progress value - 30%
            d.Value = 0.3;
            % Disappear the Image and TitleLabel component
            app.Image.Visible = false;
            app.TitleLabel.Visible = false;
            % Appear the UIAxes and CategoriesPanenl
            app.UIAxes.Visible = true;
            app.CategoriesPanel.Visible = true;
            app.RotateButton.Visible = true;
            % Progress value - 30%
            d.Value = 0.5;
            % Disable and Uncheck all the check boxes
            app.HealthyCheckBox.Value = 0;
            app.HealthyCheckBox.Enable = false;
            app.MultiplediseasesCheckBox.Value = 0;
            app.MultiplediseasesCheckBox.Enable = false;
            app.RustCheckBox.Value = 0;
            app.RustCheckBox.Enable = false;
            app.ScabCheckBox.Value = 0;
            app.ScabCheckBox.Enable = false;            
            % Start detecting object
            [bboxes, scores, labels] = detect(app.detector.detector,app.img);
            % Progress value - 90%
            d.Value = 0.9;
            % Initialize healthyBoxes and heathyLabels
            app.healthyBoxes = [];
            app.heathyLabels = [];
            % Initialize multiBoxes and multiLabels
            app.multiBoxes = [];
            app.multiLabels = [];
            % Initialize rustBoxes and rustLabels
            app.rustBoxes = [];
            app.rustLabels = [];
            % Initialize scabBoxes and scabLabels
            app.scabBoxes = [];
            app.scabLabels = [];
            if ~isempty(bboxes) % If object exist in the image
                % Embed the object boxes in the image
                img_label = insertObjectAnnotation(app.img,'Rectangle',bboxes,labels);
                % Show the image on UIAxes 
                imshow(img_label, 'Parent',app.UIAxes)
                % Collect the boxes and labels for each category
                for i=1:length(labels)
                    if(labels(i) == 'healthy')
                        app.HealthyCheckBox.Value = 1;
                        app.HealthyCheckBox.Enable = true;
                        app.healthyBoxes = [app.healthyBoxes;  bboxes(i,:)];
                        app.heathyLabels = [app.heathyLabels;  labels(i)];
                    end
                    if(labels(i) == 'multiple_diseases')
                        app.MultiplediseasesCheckBox.Value = 1;
                        app.MultiplediseasesCheckBox.Enable = true;
                        app.multiBoxes = [app.multiBoxes;  bboxes(i,:)];
                        app.multiLabels = [app.multiLabels;  labels(i)];                        
                    end
                    if(labels(i) == 'rust')
                        app.RustCheckBox.Value = 1;
                        app.RustCheckBox.Enable = true;
                        app.rustBoxes = [app.rustBoxes;  bboxes(i,:)];
                        app.rustLabels = [app.rustLabels;  labels(i)];                        
                    end
                    if(labels(i) == 'scab')
                        app.ScabCheckBox.Value = 1;
                        app.ScabCheckBox.Enable = true;
                        app.scabBoxes = [app.scabBoxes;  bboxes(i,:)];
                        app.scabLabels = [app.scabLabels;  labels(i)];                        
                    end                    
                end
            else % If object not exist in the image
                % Show the image on UIAxes
                imshow(app.img, 'Parent',app.UIAxes);
                % Show the message box
                msgbox('Object not found', 'Error','error');
            end
            % Close progress bar
            close(d);
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initDetector(app)
            app.detector = load('detector_augment_150');
        end

        % Button pushed function: SelectButton
        function openSelectFile(app, event)
            % File selection
            [filename,filepath] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            % Set text to ImagePathLabel
            app.ImagePathLabel.Text = fullname;
            % Read selected Image
            app.img = imread(fullname);
            % Foliar disease detection
            foliarDiseaseDetection(app);
        end

        % Value changed function: HealthyCheckBox, 
        % MultiplediseasesCheckBox, RustCheckBox, ScabCheckBox
        function checkBoxChange(app, event)
            new_bboxes = [];
            new_labels = [];
            if app.HealthyCheckBox.Value == 1
                new_bboxes = [new_bboxes; app.healthyBoxes];
                new_labels = [new_labels; app.heathyLabels];
            end
            if app.MultiplediseasesCheckBox.Value == 1
                new_bboxes = [new_bboxes; app.multiBoxes];
                new_labels = [new_labels; app.multiLabels];
            end           
            if app.RustCheckBox.Value == 1
                new_bboxes = [new_bboxes; app.rustBoxes];
                new_labels = [new_labels; app.rustLabels];
            end            
            if app.ScabCheckBox.Value == 1
                new_bboxes = [new_bboxes; app.scabBoxes];
                new_labels = [new_labels; app.scabLabels];
            end 
            if ~isempty(new_bboxes)
                img_label = insertObjectAnnotation(app.img,'Rectangle',new_bboxes,new_labels);
                imshow(img_label, 'Parent',app.UIAxes);
            else
                imshow(app.img, 'Parent',app.UIAxes);
            end
        end

        % Button pushed function: RotateButton
        function rotateImage(app, event)
            % Rotate the image
            app.img = imrotate(app.img,90);
            foliarDiseaseDetection(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @openSelectFile, true);
            app.SelectButton.Icon = 'photography.png';
            app.SelectButton.Text = 'Select your image';
            app.ImagePathLabel = uilabel(app.UIFigure);
            app.ImagePathLabel.Text = '';
            app.CategoriesPanel = uipanel(app.UIFigure);
            app.CategoriesPanel.Title = 'CATEGORIES';
            app.CategoriesPanel.Visible = 'off';
            app.HealthyCheckBox = uicheckbox(app.CategoriesPanel);
            app.HealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkBoxChange, true);
            app.HealthyCheckBox.Text = 'Healthy';
            app.MultiplediseasesCheckBox = uicheckbox(app.CategoriesPanel);
            app.MultiplediseasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkBoxChange, true);
            app.MultiplediseasesCheckBox.Text = 'Multiple diseases';
            app.RustCheckBox = uicheckbox(app.CategoriesPanel);
            app.RustCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkBoxChange, true);
            app.RustCheckBox.Text = 'Rust';
            app.ScabCheckBox = uicheckbox(app.CategoriesPanel);
            app.ScabCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkBoxChange, true);
            app.ScabCheckBox.Text = 'Scab';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'leaf.png';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Leaffix';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateImage, true);
            app.RotateButton.Icon = 'rotate.png';
            app.RotateButton.Visible = 'off';
            app.RotateButton.Text = 'Rotate image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initDetector)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end